classdef imaginary_overkill < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TmaxEdit             matlab.ui.control.NumericEditField
        T_maxLabel           matlab.ui.control.Label
        StepEdit             matlab.ui.control.NumericEditField
        hEditFieldLabel      matlab.ui.control.Label
        SystemDropDown       matlab.ui.control.DropDown
        SystemDropDownLabel  matlab.ui.control.Label
        BSlider              matlab.ui.control.Slider
        BSliderLabel         matlab.ui.control.Label
        ASlider              matlab.ui.control.Slider
        ASliderLabel         matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        methods_ = { @ring_euler, @ring_euler_exp }; % Система
        X0 = [0; 1]; % Начальная точка
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ASlider, BSlider
        function Calculate(app, event)
            a = app.ASlider.Value;
            b = app.BSlider.Value;
            h_global = app.StepEdit.Value;
            Tmax = app.TmaxEdit.Value;

            re_parts = [ 1, 1 ];
            im_parts = [ a, b ];
            coefs = complex(re_parts, im_parts);
            hs = coefs / sum(real(coefs)) * h_global;

            [~, x] = composition_method(app.methods_, hs, Tmax, app.X0);
        
            plot(app.UIAxes, x(1, :), x(2, :));
            title(app.UIAxes, ['Ring Test plot with', newline, ...
                'a = ', num2str(a), ', b = ', num2str(b)])
        end

        % Value changed function: SystemDropDown
        function SystemDropDownValueChanged(app, event)
            value = app.SystemDropDown.Value;
            if (strcmp(value, 'Ring'))
                app.methods_ = { @ring_euler, @ring_euler_exp };
                app.X0 = [0; 1];
            elseif (strcmp(value, 'Hamiltonian'))
                app.methods_ = { @hamiltonian_euler, @hamiltonian_euler_semi };
                app.X0 = [0; 2];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ASliderLabel = uilabel(app.UIFigure);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.UIFigure);
            app.ASlider.Limits = [-10 10];
            app.ASlider.ValueChangedFcn = createCallbackFcn(app, @Calculate, true);
            app.BSliderLabel = uilabel(app.UIFigure);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [-10 10];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @Calculate, true);
            app.SystemDropDownLabel = uilabel(app.UIFigure);
            app.SystemDropDownLabel.Text = 'System';
            app.SystemDropDown = uidropdown(app.UIFigure);
            app.SystemDropDown.Items = {'Ring', 'Hamiltonian'};
            app.SystemDropDown.ValueChangedFcn = createCallbackFcn(app, @SystemDropDownValueChanged, true);
            app.SystemDropDown.Value = 'Ring';
            app.hEditFieldLabel = uilabel(app.UIFigure);
            app.hEditFieldLabel.Interpreter = 'latex';
            app.hEditFieldLabel.Text = '$h$';
            app.StepEdit = uieditfield(app.UIFigure, 'numeric');
            app.StepEdit.LowerLimitInclusive = 'off';
            app.StepEdit.Limits = [0 Inf];
            app.StepEdit.ValueDisplayFormat = '%g';
            app.StepEdit.Value = 0.1;
            app.T_maxLabel = uilabel(app.UIFigure);
            app.T_maxLabel.Interpreter = 'latex';
            app.T_maxLabel.Text = '$T_{max}$';
            app.TmaxEdit = uieditfield(app.UIFigure, 'numeric');
            app.TmaxEdit.LowerLimitInclusive = 'off';
            app.TmaxEdit.Limits = [0 Inf];
            app.TmaxEdit.ValueDisplayFormat = '%.0f';
            app.TmaxEdit.Value = 25;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imaginary_overkill

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end